classdef crtncalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button                          matlab.ui.control.Button
        EditField2                      matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        NumberofVehiclesEditField       matlab.ui.control.NumericEditField
        NumberofVehiclesEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            // declare all variables
            // the button is where the calculation happens (here)
            // use the normal calculation ways, store in new variables 
            // i.e newVariable = oldVariable/otheroldVariable;
            // then u store the answer in a new numeric edit field
            // which i can't quite remember. use excel thing for equations
            // do two seperate bits for 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofVehiclesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofVehiclesEditFieldLabel.Text = 'Number of Vehicles';
            app.NumberofVehiclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtncalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end